classdef Crear < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        SignalParametersTab             matlab.ui.container.TabGroup
        MicrofonoTab                    matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        GridLayout29                    matlab.ui.container.GridLayout
        GuardarButton                   matlab.ui.control.Button
        ReproducirButton                matlab.ui.control.StateButton
        GrabarButton                    matlab.ui.control.StateButton
        GridLayout14                    matlab.ui.container.GridLayout
        ndeInicioTxtMicrofono           matlab.ui.control.NumericEditField
        nInicialEditFieldLabel          matlab.ui.control.Label
        TiempoGrabsTxt                  matlab.ui.control.NumericEditField
        TiempoGrabsEditFieldLabel       matlab.ui.control.Label
        NmCanalesTxt                    matlab.ui.control.NumericEditField
        NmCanalesEditFieldLabel         matlab.ui.control.Label
        BitsMuestraTxt                  matlab.ui.control.NumericEditField
        BitsMuestraEditFieldLabel       matlab.ui.control.Label
        FrecuenciadeMuestreoTxt         matlab.ui.control.NumericEditField
        FrecuenciadeMuestreoEditFieldLabel  matlab.ui.control.Label
        SimuladaTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout27                    matlab.ui.container.GridLayout
        SimularButton                   matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ImpulsoButton                   matlab.ui.control.RadioButton
        SincButton                      matlab.ui.control.RadioButton
        ChirpButton                     matlab.ui.control.RadioButton
        EscalnButton                    matlab.ui.control.RadioButton
        TriangularButton                matlab.ui.control.RadioButton
        RampaButton                     matlab.ui.control.RadioButton
        CosenoButton                    matlab.ui.control.RadioButton
        SenoButton                      matlab.ui.control.RadioButton
        DientedeSierraButton            matlab.ui.control.RadioButton
        GridLayout3                     matlab.ui.container.GridLayout
        ndeFinTxt                       matlab.ui.control.NumericEditField
        ndeFinEditField_3Label          matlab.ui.control.Label
        ndeInicioTxt                    matlab.ui.control.NumericEditField
        ndeInicioEditField_3Label       matlab.ui.control.Label
        DesplazamientoTxt               matlab.ui.control.NumericEditField
        DesplazamientoEditField_3Label  matlab.ui.control.Label
        FaseTxt                         matlab.ui.control.NumericEditField
        FaseradEditField_3Label         matlab.ui.control.Label
        FrecuenciaMuestreoTxt           matlab.ui.control.NumericEditField
        FrecuenciaMuestreoEditField_3Label  matlab.ui.control.Label
        FrecuenciaAnlogaTxt             matlab.ui.control.NumericEditField
        FrecuenciaAnlogaEditField_3Label  matlab.ui.control.Label
        Gananciatxt                     matlab.ui.control.NumericEditField
        GananciaLabel                   matlab.ui.control.Label
        ArchivoTab                      matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        GridLayout24                    matlab.ui.container.GridLayout
        GridLayout26                    matlab.ui.container.GridLayout
        GridLayout3_2                   matlab.ui.container.GridLayout
        tiempotxt                       matlab.ui.control.NumericEditField
        tiempoLabel                     matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        CargarArchivoButton             matlab.ui.control.Button
        PathLabel                       matlab.ui.control.Label
        InfoLabel                       matlab.ui.control.Label
        TarjetaTab                      matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        INPUTSButtonGroup               matlab.ui.container.ButtonGroup
        A7Button                        matlab.ui.control.RadioButton
        A6Button                        matlab.ui.control.RadioButton
        A5Button                        matlab.ui.control.RadioButton
        A4Button                        matlab.ui.control.RadioButton
        A3Button                        matlab.ui.control.RadioButton
        A2Button                        matlab.ui.control.RadioButton
        A1Button                        matlab.ui.control.RadioButton
        A0Button                        matlab.ui.control.RadioButton
        GridLayout23                    matlab.ui.container.GridLayout
        ndeInicioTxtTarjeta             matlab.ui.control.NumericEditField
        ndeinicioEditFieldLabel         matlab.ui.control.Label
        FrecuenciademuestreoTxt         matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel        matlab.ui.control.Label
        TiempoTxt                       matlab.ui.control.NumericEditField
        TiempoEditFieldLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        Main; % VentanaPrincipal
        Tipo;
        Ganancia;FrecuenciaAnaloga;FrecuenciaMuestreo;Fase;Desplazamiento;n_inicio;n_final; %Parametros
        Signal = [];

    end
    
    methods (Access = public)
        
        %uncion para Actualizar las variables globales en la clase de crear
       function Cerrar(app)
            app.Tipo = 0;
            disp('cerrando...');
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.CloseRequestFcn = @(src, event) Cerrar(app);
        end

        % Callback function
        function SealDeentradaButtonGroupSelectionChanged(app, event)
            
        end

        % Callback function
        function SignalParametersTabSelectionChanged(app, event)
            selectedTab = app.SignalParametersTab.SelectedTab;
            
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
           app.Tipo = 2;
           app.Ganancia = app.Gananciatxt.Value;
           app.FrecuenciaAnaloga = app.FrecuenciaAnlogaTxt.Value;
           app.FrecuenciaMuestreo = app.FrecuenciaMuestreoTxt.Value;
           app.Fase = app.FaseTxt.Value;
           app.Desplazamiento = app.DesplazamientoTxt.Value;
           app.n_inicio = app.ndeInicioTxt.Value;
           app.n_final = app.ndeFinTxt.Value;

           n = app.n_inicio:app.n_final;

           if  app.ButtonGroup.SelectedObject == app.SenoButton
                
               app.Signal = app.Ganancia*sin(2*pi*app.FrecuenciaAnaloga*(n-app.Desplazamiento)/app.FrecuenciaMuestreo + app.Fase);

           elseif app.ButtonGroup.SelectedObject == app.CosenoButton

               app.Signal =app.Ganancia*cos(2*pi*app.FrecuenciaAnaloga*(n-app.Desplazamiento)/app.FrecuenciaMuestreo+ app.Fase);

           elseif app.ButtonGroup.SelectedObject == app.DientedeSierraButton

               app.Signal= app.Ganancia*sawtooth((2*pi*app.FrecuenciaAnaloga*(n-app.Desplazamiento)/app.FrecuenciaMuestreo+ app.Fase)); % Diente de sierra
               

           elseif app.ButtonGroup.SelectedObject == app.RampaButton

                app.Signal = app.Ganancia*(n-app.Desplazamiento);

           elseif app.ButtonGroup.SelectedObject == app.TriangularButton

                app.Signal= app.Ganancia*sawtooth((2*pi*app.FrecuenciaAnaloga*(n-app.Desplazamiento)/app.FrecuenciaMuestreo+ app.Fase),0.5); % Triangular

           elseif app.ButtonGroup.SelectedObject == app.EscalnButton

                app.Signal = app.Ganancia*[zeros(1,abs(app.n_inicio)+app.Desplazamiento)  ones(1, abs(app.n_final)+1-app.Desplazamiento)];%Escalon

           elseif app.ButtonGroup.SelectedObject == app.ChirpButton
                
               app.Signal= app.Ganancia*chirp(2*pi*app.FrecuenciaAnaloga*(n-app.Desplazamiento)/app.FrecuenciaMuestreo+ app.Fase); % Chirp

           elseif app.ButtonGroup.SelectedObject == app.SincButton

                app.Signal= app.Ganancia*sinc(2*pi*app.FrecuenciaAnaloga*(n-app.Desplazamiento)/app.FrecuenciaMuestreo+ app.Fase); % Sinc

           elseif app.ButtonGroup.SelectedObject == app.ImpulsoButton
               
                app.Signal = app.Ganancia*[zeros(1,abs(app.n_inicio)+app.Desplazamiento)  1 zeros(1,abs(app.n_final)-app.Desplazamiento)];

           end
           
           
          uiresume(app.UIFigure);
        end

        % Value changed function: GrabarButton
        function GrabarButtonValueChanged(app, event)
            value = app.GrabarButton.Value;
            app.Tipo = 1;
            app.FrecuenciaMuestreo=app.FrecuenciadeMuestreoTxt.Value;    % Frecuencia de muestreo   
            nBit=app.BitsMuestraTxt.Value;   % Número de bits por muestra
            nChan=app.NmCanalesTxt.Value;   % Número de canales
            tRec1=app.TiempoGrabsTxt.Value;    % Tiempo de grabación en s.
            app.n_inicio = app.ndeInicioTxtMicrofono.Value;
            countName = 1;

            aud1 = audiorecorder(app.FrecuenciaMuestreo,nBit,nChan); % Crear un objeto audiorecorder
            
            disp('Iniciando la grabación...');
            
            recordblocking(aud1, tRec1); % Grabar  audio
            
            disp('Fin de la grabación.');
            
            %app.Signal = getaudiodata(aud1); % Obtener los datos de la señal x1
            app.n_final = (length(app.Signal)-1)+app.n_inicio;
            n = app.n_inicio:app.n_final;
           
            %plot(app.GraficaAudioInput,apn,app.Signal)

            uiresume(app.UIFigure);
        end

        % Value changed function: ReproducirButton
        function ReproducirButtonValueChanged(app, event)
            value = app.ReproducirButton.Value;
             if app.Fs>4000
                
                 sound(app.S1,app.Fs);
                
            else
                disp("Error frecuencia de muestreo por debajo de 4000")
            end
        end

        % Button pushed function: CargarArchivoButton
        function CargarArchivoButtonPushed(app, event)
            app.Tipo = 3;
    
            [NombreArch, pathname] = uigetfile('*.*', 'Seleccionar archivo');
            if isequal(NombreArch, 0)
                disp('No se seleccionó ningún archivo');
                
            else
                app.PathLabel.Text = fullfile(pathname, NombreArch);
                
                Tt = app.tiempotxt.Value; % tiempo total 

                NArchivo_Tipo = split(NombreArch, '.');
                if strcmp(NArchivo_Tipo(2), 'wav')
    
                    [app.Signal,app.FrecuenciaMuestreo] = audioread(fullfile(pathname,NombreArch)); % Leer el archivo de audio
                    % Información del archivo de audio.
                    info = audioinfo(NombreArch);
                    % Mostrar la información en el cuadro de texto
                    app.InfoLabel.Text = sprintf('Nombre: %s\nDuración: %f segundos\nFrecuencia de muestreo: %d Hz\nNumero de canales: %d\nCantidad de Bits: %d\nTitle: %s\nComentarios: %s\nArtista: %s\n',info.Filename, info.TotalSamples/info.SampleRate, info.SampleRate, info.NumChannels, info.BitsPerSample, info.Title, info.Comment, info.Artist);
        
                elseif strcmp(NArchivo_Tipo(2), 'txt')
    
                    fileID = fopen(NombreArch,'r');
                    app.Signal = fscanf(fileID,'%f');
                    fclose(fileID);
    
                    app.InfoLabel.Text = sprintf('Nombre: %s\nRuta: %s',NombreArch, app.PathLabel.Text);
                    
                end
                N = length(app.Signal);
                app.n_inicio = 0;
                app.n_final = N-1;
                app.FrecuenciaMuestreo = N/Tt;

                X = abs(fft(app.Signal));   % Magnitud del espectro
                f = (app.n_inicio:app.n_final) * (app.FrecuenciaMuestreo/N);  % Vector de frecuencias  
                [~, idx] = max(X(1:N/2));   % Encuentra el pico en el espectro
                app.FrecuenciaAnaloga = f(idx);  % Frecuencia dominante en Hz

                app.Ganancia = max(abs(app.Signal));  % Amplitud máxima
                
                X_fft = fft(app.Signal);  % Transformada de Fourier
                app.Fase = angle(X_fft(idx));  % Fase en radianes

                app.Desplazamiento = mean(app.Signal);

            end
            uiresume(app.UIFigure);% no esta sacando los datos del tipo 3
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.SignalParametersTab = uitabgroup(app.Panel);
            app.MicrofonoTab = uitab(app.SignalParametersTab);
            app.MicrofonoTab.Title = 'Microfono';
            app.GridLayout28 = uigridlayout(app.MicrofonoTab);
            app.GridLayout14 = uigridlayout(app.GridLayout28);
            app.FrecuenciadeMuestreoEditFieldLabel = uilabel(app.GridLayout14);
            app.FrecuenciadeMuestreoEditFieldLabel.Text = 'Frecuencia de Muestreo';
            app.FrecuenciadeMuestreoTxt = uieditfield(app.GridLayout14, 'numeric');
            app.FrecuenciadeMuestreoTxt.ValueDisplayFormat = '%.0f';
            app.FrecuenciadeMuestreoTxt.Value = 44100;
            app.BitsMuestraEditFieldLabel = uilabel(app.GridLayout14);
            app.BitsMuestraEditFieldLabel.Text = 'Bits/Muestra';
            app.BitsMuestraTxt = uieditfield(app.GridLayout14, 'numeric');
            app.BitsMuestraTxt.Value = 16;
            app.NmCanalesEditFieldLabel = uilabel(app.GridLayout14);
            app.NmCanalesEditFieldLabel.Text = 'Núm. Canales';
            app.NmCanalesTxt = uieditfield(app.GridLayout14, 'numeric');
            app.NmCanalesTxt.Value = 1;
            app.TiempoGrabsEditFieldLabel = uilabel(app.GridLayout14);
            app.TiempoGrabsEditFieldLabel.Text = 'Tiempo Grab. (s)';
            app.TiempoGrabsTxt = uieditfield(app.GridLayout14, 'numeric');
            app.TiempoGrabsTxt.Value = 3;
            app.nInicialEditFieldLabel = uilabel(app.GridLayout14);
            app.nInicialEditFieldLabel.Text = '''n'' Inicial';
            app.ndeInicioTxtMicrofono = uieditfield(app.GridLayout14, 'numeric');
            app.GridLayout29 = uigridlayout(app.GridLayout28);
            app.GrabarButton = uibutton(app.GridLayout29, 'state');
            app.GrabarButton.ValueChangedFcn = createCallbackFcn(app, @GrabarButtonValueChanged, true);
            app.GrabarButton.Text = 'Grabar';
            app.ReproducirButton = uibutton(app.GridLayout29, 'state');
            app.ReproducirButton.ValueChangedFcn = createCallbackFcn(app, @ReproducirButtonValueChanged, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.GuardarButton = uibutton(app.GridLayout29, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.SimuladaTab = uitab(app.SignalParametersTab);
            app.SimuladaTab.Title = 'Simulada';
            app.GridLayout2 = uigridlayout(app.SimuladaTab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GananciaLabel = uilabel(app.GridLayout3);
            app.GananciaLabel.Text = 'Ganancia:';
            app.Gananciatxt = uieditfield(app.GridLayout3, 'numeric');
            app.Gananciatxt.Value = 1.5;
            app.FrecuenciaAnlogaEditField_3Label = uilabel(app.GridLayout3);
            app.FrecuenciaAnlogaEditField_3Label.Text = 'Frecuencia Análoga:';
            app.FrecuenciaAnlogaTxt = uieditfield(app.GridLayout3, 'numeric');
            app.FrecuenciaAnlogaTxt.Value = 20;
            app.FrecuenciaMuestreoEditField_3Label = uilabel(app.GridLayout3);
            app.FrecuenciaMuestreoEditField_3Label.Text = 'Frecuencia Muestreo:';
            app.FrecuenciaMuestreoTxt = uieditfield(app.GridLayout3, 'numeric');
            app.FrecuenciaMuestreoTxt.Value = 400;
            app.FaseradEditField_3Label = uilabel(app.GridLayout3);
            app.FaseradEditField_3Label.Text = 'Fase (rad):';
            app.FaseTxt = uieditfield(app.GridLayout3, 'numeric');
            app.FaseTxt.Value = 0.5;
            app.DesplazamientoEditField_3Label = uilabel(app.GridLayout3);
            app.DesplazamientoEditField_3Label.Text = 'Desplazamiento:';
            app.DesplazamientoTxt = uieditfield(app.GridLayout3, 'numeric');
            app.DesplazamientoTxt.Value = 2;
            app.ndeInicioEditField_3Label = uilabel(app.GridLayout3);
            app.ndeInicioEditField_3Label.Text = '''n'' de Inicio:';
            app.ndeInicioTxt = uieditfield(app.GridLayout3, 'numeric');
            app.ndeInicioTxt.Value = -5;
            app.ndeFinEditField_3Label = uilabel(app.GridLayout3);
            app.ndeFinEditField_3Label.Text = '''n'' de Fin:';
            app.ndeFinTxt = uieditfield(app.GridLayout3, 'numeric');
            app.ndeFinTxt.Value = 50;
            app.GridLayout27 = uigridlayout(app.GridLayout2);
            app.ButtonGroup = uibuttongroup(app.GridLayout27);
            app.DientedeSierraButton = uiradiobutton(app.ButtonGroup);
            app.DientedeSierraButton.Text = 'Diente de Sierra';
            app.SenoButton = uiradiobutton(app.ButtonGroup);
            app.SenoButton.Text = 'Seno';
            app.SenoButton.Value = true;
            app.CosenoButton = uiradiobutton(app.ButtonGroup);
            app.CosenoButton.Text = 'Coseno';
            app.RampaButton = uiradiobutton(app.ButtonGroup);
            app.RampaButton.Text = 'Rampa';
            app.TriangularButton = uiradiobutton(app.ButtonGroup);
            app.TriangularButton.Text = 'Triangular';
            app.EscalnButton = uiradiobutton(app.ButtonGroup);
            app.EscalnButton.Text = 'Escalón';
            app.ChirpButton = uiradiobutton(app.ButtonGroup);
            app.ChirpButton.Text = 'Chirp';
            app.SincButton = uiradiobutton(app.ButtonGroup);
            app.SincButton.Text = 'Sinc';
            app.ImpulsoButton = uiradiobutton(app.ButtonGroup);
            app.ImpulsoButton.Text = 'Impulso';
            app.SimularButton = uibutton(app.GridLayout27, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.ArchivoTab = uitab(app.SignalParametersTab);
            app.ArchivoTab.Title = 'Archivo';
            app.GridLayout15 = uigridlayout(app.ArchivoTab);
            app.GridLayout24 = uigridlayout(app.GridLayout15);
            app.InfoLabel = uilabel(app.GridLayout24);
            app.InfoLabel.Text = '';
            app.GridLayout26 = uigridlayout(app.GridLayout24);
            app.PathLabel = uilabel(app.GridLayout26);
            app.PathLabel.Text = 'Dirección de archivo...';
            app.GridLayout7 = uigridlayout(app.GridLayout26);
            app.CargarArchivoButton = uibutton(app.GridLayout7, 'push');
            app.CargarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivoButtonPushed, true);
            app.CargarArchivoButton.Text = 'Cargar Archivo';
            app.GridLayout3_2 = uigridlayout(app.GridLayout26);
            app.tiempoLabel = uilabel(app.GridLayout3_2);
            app.tiempoLabel.Text = 'Tiempo del audio o señal (s):';
            app.tiempotxt = uieditfield(app.GridLayout3_2, 'numeric');
            app.tiempotxt.Value = 0.0025;
            app.TarjetaTab = uitab(app.SignalParametersTab);
            app.TarjetaTab.Title = 'Tarjeta';
            app.GridLayout16 = uigridlayout(app.TarjetaTab);
            app.GridLayout23 = uigridlayout(app.GridLayout16);
            app.TiempoEditFieldLabel = uilabel(app.GridLayout23);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoTxt = uieditfield(app.GridLayout23, 'numeric');
            app.TiempoTxt.Value = 10;
            app.FrecuenciaEditFieldLabel = uilabel(app.GridLayout23);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia: ';
            app.FrecuenciademuestreoTxt = uieditfield(app.GridLayout23, 'numeric');
            app.FrecuenciademuestreoTxt.Value = 1000;
            app.ndeinicioEditFieldLabel = uilabel(app.GridLayout23);
            app.ndeinicioEditFieldLabel.Text = '''n'' de inicio';
            app.ndeInicioTxtTarjeta = uieditfield(app.GridLayout23, 'numeric');
            app.INPUTSButtonGroup = uibuttongroup(app.GridLayout16);
            app.INPUTSButtonGroup.Title = 'INPUTS';
            app.A0Button = uiradiobutton(app.INPUTSButtonGroup);
            app.A0Button.Text = 'A0';
            app.A0Button.Value = true;
            app.A1Button = uiradiobutton(app.INPUTSButtonGroup);
            app.A1Button.Text = 'A1';
            app.A2Button = uiradiobutton(app.INPUTSButtonGroup);
            app.A2Button.Text = 'A2';
            app.A3Button = uiradiobutton(app.INPUTSButtonGroup);
            app.A3Button.Text = 'A3';
            app.A4Button = uiradiobutton(app.INPUTSButtonGroup);
            app.A4Button.Text = 'A4';
            app.A5Button = uiradiobutton(app.INPUTSButtonGroup);
            app.A5Button.Text = 'A5';
            app.A6Button = uiradiobutton(app.INPUTSButtonGroup);
            app.A6Button.Text = 'A6';
            app.A7Button = uiradiobutton(app.INPUTSButtonGroup);
            app.A7Button.Text = 'A7';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crear

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end